classdef RippleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        BrowseforfileButton             matlab.ui.control.Button
        TimevectorStructEditField       matlab.ui.control.EditField
        TimevectorStructEditFieldLabel  matlab.ui.control.Label
        LFPStructEditField              matlab.ui.control.EditField
        LFPStructEditFieldLabel         matlab.ui.control.Label
        LFPDataFilepathEditField        matlab.ui.control.EditField
        LFPDataFilepathEditFieldLabel   matlab.ui.control.Label
        PositionPlotFocusDropDown       matlab.ui.control.DropDown
        PositionPlotFocusDropDownLabel  matlab.ui.control.Label
        StddevcutoffEditField           matlab.ui.control.NumericEditField
        StddevcutoffEditFieldLabel      matlab.ui.control.Label
        MindurationsamplesEditField     matlab.ui.control.NumericEditField
        MindurationsamplesEditFieldLabel  matlab.ui.control.Label
        SWRwindowsamplesEditField       matlab.ui.control.NumericEditField
        SWRwindowsamplesEditFieldLabel  matlab.ui.control.Label
        BandHzSlider                    matlab.ui.control.RangeSlider
        BandHzSliderLabel               matlab.ui.control.Label
        FindRipplesButton               matlab.ui.control.Button
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        LFPDataSelectionLabel           matlab.ui.control.Label
        TaskDropDown                    matlab.ui.control.DropDown
        TaskDropDownLabel               matlab.ui.control.Label
        SWRdetectionparamsLabel         matlab.ui.control.Label
        SamplingFreqHzEditField         matlab.ui.control.NumericEditField
        SamplingFreqHzEditFieldLabel    matlab.ui.control.Label
        LoadLFPDataButton               matlab.ui.control.Button
        AnimalNameDropDown              matlab.ui.control.DropDown
        AnimalNameDropDownLabel         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        LFPOverviewTextArea             matlab.ui.control.TextArea
        LFPOverviewLabel                matlab.ui.control.Label
        RipplesOverviewTextArea         matlab.ui.control.TextArea
        RipplesOverviewLabel            matlab.ui.control.Label
        ShowDetectionThresholdCheckBox  matlab.ui.control.CheckBox
        ShowLFPCheckBox                 matlab.ui.control.CheckBox
        ShowRippleFilterCheckBox        matlab.ui.control.CheckBox
        ShowEnvelopeCheckBox            matlab.ui.control.CheckBox
        ShowSharpWaveFilterCheckBox     matlab.ui.control.CheckBox
        GotoLabel                       matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        SWRPropertiesLabel              matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        NextButton                      matlab.ui.control.Button
        PrevButton                      matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        experiment % LFPExperiment object
    end
    
    methods (Access = private)
        function updateSWREventPlot(app)
            if ~isempty(app.experiment.swr_events{1}) && app.EditField.Value <= numel(app.experiment.swr_events{1})
                currentRipple = app.experiment.swr_events{1}(app.EditField.Value);
                ripple_info = currentRipple.getProperties();  % props of the base ripple
                additionalInfo = sprintf('Showing SWR Event: %d\n', app.EditField.Value);
                app.TextArea.Value = [additionalInfo, newline, ripple_info];
                %app.experiment.displayMergedEvent(app.UIAxes3, app.current_displayed_ripple_id);

                showLFP = app.ShowLFPCheckBox.Value;
                showEnvelope = app.ShowEnvelopeCheckBox.Value;
                showSW = app.ShowSharpWaveFilterCheckBox.Value;
                showRip = app.ShowRippleFilterCheckBox.Value;
                showDet = app.ShowDetectionThresholdCheckBox.Value;
                app.experiment.plotSwrEvent(app.UIAxes3, app.EditField.Value, showLFP, showEnvelope, showSW, showRip,showDet, app.UIAxes)
            else
                cla(app.UIAxes3, 'reset');
                app.TextArea.Value = "No Ripples Found!";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppOnReady(app)
            % Called at beginning of app
            disp("%%%%%%%%%%%%%%%%%%%%%%")
            disp("RippleApp Running")
            % Create an instance of LFPExperiment
            %disp("Empty LFPExperiment Object created")
        end

        % Button pushed function: LoadLFPDataButton
        function LoadDataSet(app, event)
            app.experiment = LFPExperiment();
            lfp_struct_name = app.LFPStructEditField.Value;
            time_struct_name = app.TimevectorStructEditField.Value;

            app.experiment = app.experiment.getLfpDataFromFile(app.LFPDataFilepathEditField.Value, lfp_struct_name, time_struct_name);
            
            
            % Check if data was successfully loaded
            if isempty(app.experiment.lfp_data{1}) || isempty(app.experiment.time_vec) || isnan(app.experiment.sf)
                % Provide feedback to the user about the failed load
                app.LFPOverviewTextArea.Value = {'Error: Failed to load LFP data. Please check the file and struct names.'};
                return;
            end
        
            % If data is valid, update the LFP overview text area
            app.LFPOverviewTextArea.Value = app.experiment.getLfpProperties();
            
            %selectedAnimalName = app.AnimalNameDropDown.Value;
            %if app.DataSourceDropDown.Value == "crcns-hc28"
            %    selectedTaskName = app.TaskDropDown.Value;
            %    lfp_data = get_lfp_data_hc28(selectedAnimalName, selectedTaskName, app.TotalTetrodesEditField.Value);
                % This dataset has position data, so use it
            %    pos_data = get_pos_data(selectedAnimalName, selectedTaskName);  % has .x, .y, .v, .t
            %    app.experiment = LFP_Experiment(lfp_data, app.SamplingFreqHzEditField.Value, pos_data);
               % Plot position data
            %    if app.PositionPlotFocusDropDown.Value == "Velocity"
                    % Link the x-axes of the two plots
            %        linkaxes([app.UIAxes, app.UIAxes2], 'x');
            %        plotVelocity(app.UIAxes2, app.experiment.pos_data);
            %    elseif app.PositionPlotFocusDropDown.Value == "Position"
            %        % Unlink the x-axes of the two plots
            %        linkaxes([app.UIAxes, app.UIAxes2], 'off');
            %        plotPosition(app.UIAxes2, app.experiment.pos_data)
            %    end
            
            
            %elseif app.DataSourceDropDown.Value == "Calegari"
            %    lfp_data = get_lfp_data_cg(selectedAnimalName);
                % set the tetrodes number dynamically based on which animal
                % was picked
            %    app.TotalTetrodesEditField.Value = length(lfp_data);
                % Here leave pos_data blank
            %    app.experiment = LFP_Experiment(lfp_data, app.SamplingFreqHzEditField.Value, []);
            
            %end
            
            % Plot the LFP data for all tetrodes by default
            cla(app.UIAxes, 'reset');
            app.experiment.plotLfpDataOnAxes(app.UIAxes);


        end

        % Callback function
        function AnimalDataDrop(app, event)
            %value = app.AnimalNameDropDown.Value;
            
        end

        % Callback function
        function DataDropDownValueChanged(app, event)

        end

        % Callback function
        function PlotRangeStartChanged(app, event)
            %value = app.PlotrangestartsEditField.Value;
            %plotLFPDataOnAxes(app.UIAxes, app.experiment.lfp_data, app.experiment.time_vec, app.PlotrangestartsEditField.Value, app.PlotrangeendsEditField.Value, app.yOffset);
        end

        % Callback function
        function PlotRangeEndChanged(app, event)
            %value = app.PlotrangeendsEditField.Value;
            %plotLFPDataOnAxes(app.UIAxes, app.experiment.lfp_data, app.experiment.time_vec, app.PlotrangestartsEditField.Value, app.PlotrangeendsEditField.Value, app.yOffset);
        end

        % Button down function: LeftPanel
        function ResetButtonPressed(app, event)
            % does nothing lmao
            %app.PlotrangestartsEditField.Value = 0;
            %app.PlotrangeendsEditField.Value = app.experiment.total_time;
        end

        % Button pushed function: FindRipplesButton
        function FindRippleButtonPushed(app, event)
            % Find Ripples
            app.experiment = app.experiment.detectRipples(app.StddevcutoffEditField.Value, app.BandHzSlider.Value, app.SWRwindowsamplesEditField.Value, app.MindurationsamplesEditField.Value);
            % Upddate Info on found ripples
            ripples_info = app.experiment.getRippleDetectionSummary();
            app.RipplesOverviewTextArea.Value = ripples_info;
            % Merge
            %app.experiment = app.experiment.mergeMultiTetrodeSwrEvents();
            % Clear the plot to handle extra button presses
            cla(app.UIAxes, 'reset');
            % Replot the LFP data and then ripples on top
            app.experiment.plotLfpDataOnAxes(app.UIAxes);
            app.experiment.drawSwrEvents(app.UIAxes);

            % Reset GoTo ripple edit field index to 1
            app.EditField.Value = 1;
            % Draw first ripple in ripple plot
            updateSWREventPlot(app);
        end

        % Value changed function: BandHzSlider
        function MinSWRFreqBandChanged(app, event)
            %value = app.BandHzSlider.Value;
            
        end

        % Value changing function: BandHzSlider
        function MaxSWRFreqBandChanged(app, event)
            %changingValue = event.Value;            
        end

        % Callback function
        function MouseTaskPlotButton(app, event)

        end

        % Callback function
        function PositionPlotUpdatePressed(app, event)

        end

        % Callback function
        function numTetrodesChanged(app, event)
            value = app.TotalTetrodesEditField.Value;
            %LoadDataSet(app, event)  % update the plot by simulating button press
        end

        % Value changed function: PositionPlotFocusDropDown
        function PositionPlotFocusChanged(app, event)
            value = app.PositionPlotFocusDropDown.Value;
            % Plot position data
            if app.PositionPlotFocusDropDown.Value == "Velocity"
                % Link the x-axes of the two plots
                linkaxes([app.UIAxes, app.UIAxes2], 'x');
                plotVelocity(app.UIAxes2, app.experiment.pos_data);
            elseif app.PositionPlotFocusDropDown.Value == "Position"
                % Unlink the x-axes of the two plots
                linkaxes([app.UIAxes, app.UIAxes2], 'off');
                plotPosition(app.UIAxes2, app.experiment.pos_data)
            end
        end

        % Button pushed function: PrevButton
        function ripple_id_prev_pressed(app, event)
            app.EditField.Value = max(1, app.EditField.Value - 1);
            updateSWREventPlot(app);
        end

        % Button pushed function: NextButton
        function ripple_id_next_pressed(app, event)
            max_ripples = length(app.experiment.swr_events{1});
            app.EditField.Value = min(max_ripples, app.EditField.Value + 1);
            updateSWREventPlot(app);
        end

        % Value changed function: EditField
        function ripple_id_set(app, event)
            value = app.EditField.Value;
            max_ripples = length(app.experiment.swr_events{1});  % Adjust based on your actual array
            app.EditField.Value = max(1, min(max_ripples, value));
            updateSWREventPlot(app);
        end

        % Callback function
        function DataSourceDropdownValueChanged(app, event)
            value = app.DataSourceDropDown.Value;
            % Source data needs to be updated, as well as buttons
            if value == "Calegari"
                app.AnimalNameDropDown.Items = {'EAN005'};
                app.TaskDropDown.Items = {'NONE'};
                app.SamplingFreqHzEditField.Value = 1000;
                app.TotalTetrodesEditField.Value = 6;
                app.yOffset = 1000;
                app.BandHzSlider.Value = [110, 240];
                app.StddevcutoffEditField.Value = 5;
                app.MindurationsamplesEditField.Value = 10;
            elseif value == "crcns-hc28"
                app.AnimalNameDropDown.Items = {'HPa', 'HPb', 'HPc'};
                app.TaskDropDown.Items = {...
                    'Day1_Task2: run - lin', ...
                    'Day1_Task4: run - wtr1', ...
                    'Day1_Task6: run - wtr1', ...
                    'Day2_Task2: run - wtr1', ...
                    'Day2_Task4: run - wtr1', ...
                    'Day3_Task2: run - wtr1', ...
                    'Day3_Task4: run - wtr1', ...
                    'Day4_Task2: run - wtr1', ...
                    'Day4_Task4: run - wtr1', ...
                    'Day5_Task2: run - wtr1', ...
                    'Day5_Task4: run - wtr1', ...
                    'Day6_Task2: run - wtr1', ...
                    'Day6_Task4: run - wtr2', ...
                    'Day7_Task2: run - wtr1', ...
                    'Day7_Task4: run - wtr2', ...
                    'Day8_Task2: run - wtr1', ...
                    'Day8_Task4: run - wtr2'...
                };
                app.SamplingFreqHzEditField.Value = 1500;
                app.TotalTetrodesEditField.Value = 7;
                app.yOffset = 500;
                app.BandHzSlider.Value = [ 150, 250];
                app.StddevcutoffEditField.Value = 2;
                app.MindurationsamplesEditField.Value = 45;
            end
        end

        % Button pushed function: BrowseforfileButton
        function BrowseButtonPushed(app, event)
            % Prompt the user to select a .mat file
            [file, path] = uigetfile('*.mat', 'Select the .mat file to load', 'MultiSelect', 'off');
            if isequal(file, 0)
                disp('User canceled file selection.');
            else
                % Construct the full file path
                selectedFile = fullfile(path, file);
                app.LFPDataFilepathEditField.Value = selectedFile;
                
                % Bring the app back to focus (if minimized)
                figure(app.UIFigure); % Replace "UIFigure" with the actual name of your app figure
                disp(['Selected file: ', selectedFile]);
            end
        end

        % Callback function
        function FIRCheckBoxChanged(app, event)
            %value = app.ShowSharpWaveFilterCheckBox.Value;
            updateSWREventPlot(app);
        end

        % Value changed function: ShowEnvelopeCheckBox
        function EnvelopeCheckBoxChanged(app, event)
            %value = app.ShowEnvelopeCheckBox.Value;
            updateSWREventPlot(app);
        end

        % Callback function
        function ShowFilteredTraceBox(app, event)
            %value = app.ShowRippleFilterCheckBox.Value;
            %
        end

        % Value changed function: ShowLFPCheckBox
        function ShowLFPCheckBoxChecked(app, event)
            updateSWREventPlot(app);
            
        end

        % Value changed function: ShowSharpWaveFilterCheckBox
        function SWCheckBoxChanged(app, event)
            updateSWREventPlot(app);
            
        end

        % Value changed function: ShowRippleFilterCheckBox
        function RippleFilteredTraceBox(app, event)
            updateSWREventPlot(app);
            
        end

        % Value changed function: ShowDetectionThresholdCheckBox
        function DetThreshChecked(app, event)
            updateSWREventPlot(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {642, 642};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {265, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @ResetButtonPressed, true);
            app.UIAxes2 = uiaxes(app.LeftPanel);
            title(app.UIAxes2, 'Animal Position')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.AnimalNameDropDownLabel = uilabel(app.LeftPanel);
            app.AnimalNameDropDownLabel.Visible = 'off';
            app.AnimalNameDropDownLabel.Text = 'Animal Name';
            app.AnimalNameDropDown = uidropdown(app.LeftPanel);
            app.AnimalNameDropDown.Items = {'EAN-005'};
            app.AnimalNameDropDown.Visible = 'off';
            app.AnimalNameDropDown.Value = 'EAN-005';
            app.LoadLFPDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadLFPDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSet, true);
            app.LoadLFPDataButton.Text = 'Load LFP Data';
            app.SamplingFreqHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingFreqHzEditFieldLabel.Visible = 'off';
            app.SamplingFreqHzEditFieldLabel.Text = 'Sampling Freq (Hz)';
            app.SamplingFreqHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingFreqHzEditField.Limits = [0 100000];
            app.SamplingFreqHzEditField.RoundFractionalValues = 'on';
            app.SamplingFreqHzEditField.Visible = 'off';
            app.SamplingFreqHzEditField.Value = 1500;
            app.SWRdetectionparamsLabel = uilabel(app.LeftPanel);
            app.SWRdetectionparamsLabel.Text = 'SWR detection params';
            app.TaskDropDownLabel = uilabel(app.LeftPanel);
            app.TaskDropDownLabel.Visible = 'off';
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.LeftPanel);
            app.TaskDropDown.Items = {};
            app.TaskDropDown.Visible = 'off';
            app.TaskDropDown.Value = {};
            app.LFPDataSelectionLabel = uilabel(app.LeftPanel);
            app.LFPDataSelectionLabel.Text = 'LFP Data Selection';
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'RP_Detect'};
            app.MethodDropDown.Value = 'RP_Detect';
            app.FindRipplesButton = uibutton(app.LeftPanel, 'push');
            app.FindRipplesButton.ButtonPushedFcn = createCallbackFcn(app, @FindRippleButtonPushed, true);
            app.FindRipplesButton.Text = 'Find Ripples';
            app.BandHzSliderLabel = uilabel(app.LeftPanel);
            app.BandHzSliderLabel.Text = 'Band (Hz)';
            app.BandHzSlider = uislider(app.LeftPanel, 'range');
            app.BandHzSlider.Limits = [100 300];
            app.BandHzSlider.ValueChangedFcn = createCallbackFcn(app, @MinSWRFreqBandChanged, true);
            app.BandHzSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSWRFreqBandChanged, true);
            app.BandHzSlider.Step = 5;
            app.BandHzSlider.Value = [150 250];
            app.SWRwindowsamplesEditFieldLabel = uilabel(app.LeftPanel);
            app.SWRwindowsamplesEditFieldLabel.Text = 'SWR window (samples)';
            app.SWRwindowsamplesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SWRwindowsamplesEditField.Limits = [0 1000];
            app.SWRwindowsamplesEditField.Value = 225;
            app.MindurationsamplesEditFieldLabel = uilabel(app.LeftPanel);
            app.MindurationsamplesEditFieldLabel.Text = 'Min duration (samples)';
            app.MindurationsamplesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MindurationsamplesEditField.Limits = [0 100];
            app.MindurationsamplesEditField.Value = 25;
            app.StddevcutoffEditFieldLabel = uilabel(app.LeftPanel);
            app.StddevcutoffEditFieldLabel.Text = 'Std. dev cutoff';
            app.StddevcutoffEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StddevcutoffEditField.Limits = [0 20];
            app.StddevcutoffEditField.RoundFractionalValues = 'on';
            app.StddevcutoffEditField.Value = 2;
            app.PositionPlotFocusDropDownLabel = uilabel(app.LeftPanel);
            app.PositionPlotFocusDropDownLabel.Visible = 'off';
            app.PositionPlotFocusDropDownLabel.Text = 'Position Plot Focus';
            app.PositionPlotFocusDropDown = uidropdown(app.LeftPanel);
            app.PositionPlotFocusDropDown.Items = {'Position', 'Velocity'};
            app.PositionPlotFocusDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionPlotFocusChanged, true);
            app.PositionPlotFocusDropDown.Visible = 'off';
            app.PositionPlotFocusDropDown.Value = 'Velocity';
            app.LFPDataFilepathEditFieldLabel = uilabel(app.LeftPanel);
            app.LFPDataFilepathEditFieldLabel.Text = 'LFP Data Filepath';
            app.LFPDataFilepathEditField = uieditfield(app.LeftPanel, 'text');
            app.LFPDataFilepathEditField.Value = 'D:\Desktop\Shonali project\ean_data\conc_output\ean005_tetrode_1_data.mat';
            app.LFPStructEditFieldLabel = uilabel(app.LeftPanel);
            app.LFPStructEditFieldLabel.Text = 'LFP Struct';
            app.LFPStructEditField = uieditfield(app.LeftPanel, 'text');
            app.LFPStructEditField.Value = 'conc_lfp_data';
            app.TimevectorStructEditFieldLabel = uilabel(app.LeftPanel);
            app.TimevectorStructEditFieldLabel.Text = 'Time vector Struct';
            app.TimevectorStructEditField = uieditfield(app.LeftPanel, 'text');
            app.TimevectorStructEditField.Value = 'conc_time_vec';
            app.BrowseforfileButton = uibutton(app.LeftPanel, 'push');
            app.BrowseforfileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseforfileButton.Text = 'Browse for file';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'SWR Event')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PrevButton = uibutton(app.RightPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @ripple_id_prev_pressed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.RightPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @ripple_id_next_pressed, true);
            app.NextButton.Text = 'Next';
            app.TextArea = uitextarea(app.RightPanel);
            app.SWRPropertiesLabel = uilabel(app.RightPanel);
            app.SWRPropertiesLabel.Text = 'SWR Properties';
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ripple_id_set, true);
            app.EditField.Value = 1;
            app.GotoLabel = uilabel(app.RightPanel);
            app.GotoLabel.Text = 'Go to:';
            app.ShowSharpWaveFilterCheckBox = uicheckbox(app.RightPanel);
            app.ShowSharpWaveFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SWCheckBoxChanged, true);
            app.ShowSharpWaveFilterCheckBox.Text = 'Show Sharp Wave Filter';
            app.ShowSharpWaveFilterCheckBox.Value = true;
            app.ShowEnvelopeCheckBox = uicheckbox(app.RightPanel);
            app.ShowEnvelopeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnvelopeCheckBoxChanged, true);
            app.ShowEnvelopeCheckBox.Text = 'Show Envelope';
            app.ShowRippleFilterCheckBox = uicheckbox(app.RightPanel);
            app.ShowRippleFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @RippleFilteredTraceBox, true);
            app.ShowRippleFilterCheckBox.Text = 'Show Ripple Filter';
            app.ShowLFPCheckBox = uicheckbox(app.RightPanel);
            app.ShowLFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLFPCheckBoxChecked, true);
            app.ShowLFPCheckBox.Text = 'Show LFP';
            app.ShowLFPCheckBox.Value = true;
            app.ShowDetectionThresholdCheckBox = uicheckbox(app.RightPanel);
            app.ShowDetectionThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetThreshChecked, true);
            app.ShowDetectionThresholdCheckBox.Text = 'Show Detection Threshold';
            app.ShowDetectionThresholdCheckBox.Value = true;
            app.RipplesOverviewLabel = uilabel(app.RightPanel);
            app.RipplesOverviewLabel.Text = {'Ripples'; 'Overview'};
            app.RipplesOverviewTextArea = uitextarea(app.RightPanel);
            app.LFPOverviewLabel = uilabel(app.RightPanel);
            app.LFPOverviewLabel.Text = {'LFP'; 'Overview'};
            app.LFPOverviewTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RippleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @AppOnReady)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end